classdef HT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HT2UIFigure    matlab.ui.Figure
        RoSliderLabel  matlab.ui.control.Label
        RoSlider       matlab.ui.control.Slider
        RnSliderLabel  matlab.ui.control.Label
        RnSlider       matlab.ui.control.Slider
        c0SliderLabel  matlab.ui.control.Label
        c0Slider       matlab.ui.control.Slider
        UIAxes2_2      matlab.ui.control.UIAxes
        UIAxes3_2      matlab.ui.control.UIAxes
        UIAxes2_3      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function dSliderValueChanged(app, event)
            
            
        end

        % Value changed function: c0Slider
        function c0SliderValueChanged(app, event)
            c0 = app.c0Slider.Value;
            Ro = app.RoSlider.Value;
            Rn = app.RnSlider.Value;
            x=-3:0.01:6;
            int0 = [-.5,.5];
            lb = int0(1)+(int0(2)-int0(1))*Rn;
            ub = int0(2) + (int0(2)-lb)*Ro/(1-Ro);
            int1 = [lb,ub];
            y_1=unifpdf(x,int0(1),int0(2));
            y_2=unifpdf(x,int1(1),int1(2));
            %--%
            
            plot(app.UIAxes2_3, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes2_3)
            plot(app.UIAxes2_3, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes2_3, [c0 c0], [0 1], '-', 'Color',[0 0 0])
            hold(app.UIAxes2_3)
            xlim(app.UIAxes2_3, [-3, 6])
            % Plot ROC
            param_all = [Ro, Rn];
            [HR, FAR, zHR, zFAR]=getROC('2HT',param_all);
            [FAR0, zFAR0]=c2FAR('2HT',c0,param_all);
            [HR0, zHR0]=c2HR('2HT',c0,param_all);
            plot(app.UIAxes2_2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2_2)
            plot(app.UIAxes2_2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2_2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2_2)
            % Plot zROC
            plot(app.UIAxes3_2, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3_2)
            plot(app.UIAxes3_2, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3_2, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3_2)
            xlim(app.UIAxes3_2, [-3, 3])
            ylim(app.UIAxes3_2, [-3, 3])
            
            
        end

        % Value changed function: RoSlider
        function RoSliderValueChanged(app, event)
            
            c0 = app.c0Slider.Value;
            Ro = app.RoSlider.Value;
            Rn = app.RnSlider.Value;
            x=-3:0.01:6;
            int0 = [-.5,.5];
            lb = int0(1)+(int0(2)-int0(1))*Rn;
            ub = int0(2) + (int0(2)-lb)*Ro/(1-Ro);
            int1 = [lb,ub];
            y_1=unifpdf(x,int0(1),int0(2));
            y_2=unifpdf(x,int1(1),int1(2));
            %--%
            
            plot(app.UIAxes2_3, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes2_3)
            plot(app.UIAxes2_3, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes2_3, [c0 c0], [0 1], '-', 'Color',[0 0 0])
            hold(app.UIAxes2_3)
            xlim(app.UIAxes2_3, [-3, 6])
            % Plot ROC
            param_all = [Ro, Rn];
            [HR, FAR, zHR, zFAR]=getROC('2HT',param_all);
            [FAR0, zFAR0]=c2FAR('2HT',c0,param_all);
            [HR0, zHR0]=c2HR('2HT',c0,param_all);
            plot(app.UIAxes2_2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2_2)
            plot(app.UIAxes2_2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2_2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2_2)
            % Plot zROC
            plot(app.UIAxes3_2, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3_2)
            plot(app.UIAxes3_2, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3_2, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3_2)
            xlim(app.UIAxes3_2, [-3, 3])
            ylim(app.UIAxes3_2, [-3, 3])
            
        end

        % Value changed function: RnSlider
        function RnSliderValueChanged(app, event)
            
            c0 = app.c0Slider.Value;
            Ro = app.RoSlider.Value;
            Rn = app.RnSlider.Value;
            x=-3:0.01:6;
            int0 = [-.5,.5];
            lb = int0(1)+(int0(2)-int0(1))*Rn;
            ub = int0(2) + (int0(2)-lb)*Ro/(1-Ro);
            int1 = [lb,ub];
            y_1=unifpdf(x,int0(1),int0(2));
            y_2=unifpdf(x,int1(1),int1(2));
            %--%
            
            plot(app.UIAxes2_3, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes2_3)
            plot(app.UIAxes2_3, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes2_3, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes2_3, [c0 c0], [0 1], '-', 'Color',[0 0 0])
            hold(app.UIAxes2_3)
            xlim(app.UIAxes2_3, [-3, 6])
            % Plot ROC
            param_all = [Ro, Rn];
            [HR, FAR, zHR, zFAR]=getROC('2HT',param_all);
            [FAR0, zFAR0]=c2FAR('2HT',c0,param_all);
            [HR0, zHR0]=c2HR('2HT',c0,param_all);
            plot(app.UIAxes2_2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2_2)
            plot(app.UIAxes2_2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2_2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2_2)
            % Plot zROC
            plot(app.UIAxes3_2, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3_2)
            plot(app.UIAxes3_2, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3_2, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3_2)
            xlim(app.UIAxes3_2, [-3, 3])
            ylim(app.UIAxes3_2, [-3, 3])
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HT2UIFigure = uifigure('Visible', 'off');
            app.HT2UIFigure.Name = 'HT2';
            app.RoSliderLabel = uilabel(app.HT2UIFigure);
            app.RoSliderLabel.Text = 'Ro';
            app.RoSlider = uislider(app.HT2UIFigure);
            app.RoSlider.Limits = [0 1];
            app.RoSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.RoSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.RoSlider.ValueChangedFcn = createCallbackFcn(app, @RoSliderValueChanged, true);
            app.RoSlider.MinorTicks = [];
            app.RoSlider.Value = 0.75;
            app.RnSliderLabel = uilabel(app.HT2UIFigure);
            app.RnSliderLabel.Text = 'Rn';
            app.RnSlider = uislider(app.HT2UIFigure);
            app.RnSlider.Limits = [0 1];
            app.RnSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.RnSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.RnSlider.ValueChangedFcn = createCallbackFcn(app, @RnSliderValueChanged, true);
            app.RnSlider.MinorTicks = [];
            app.c0SliderLabel = uilabel(app.HT2UIFigure);
            app.c0SliderLabel.Text = 'c0';
            app.c0Slider = uislider(app.HT2UIFigure);
            app.c0Slider.Limits = [-2 2];
            app.c0Slider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.c0Slider.MajorTickLabels = {'-3', '-2', '-1', '0', '1', '2', '3'};
            app.c0Slider.ValueChangedFcn = createCallbackFcn(app, @c0SliderValueChanged, true);
            app.c0Slider.MinorTicks = [];
            app.UIAxes2_2 = uiaxes(app.HT2UIFigure);
            title(app.UIAxes2_2, 'ROC')
            xlabel(app.UIAxes2_2, 'FA Rate')
            ylabel(app.UIAxes2_2, 'Hit Rate')
            app.UIAxes3_2 = uiaxes(app.HT2UIFigure);
            title(app.UIAxes3_2, 'zROC')
            xlabel(app.UIAxes3_2, 'z(FA Rate)')
            ylabel(app.UIAxes3_2, 'z(HIt Rate)')
            app.UIAxes2_3 = uiaxes(app.HT2UIFigure);
            title(app.UIAxes2_3, 'High-Threshold Component')
            xlabel(app.UIAxes2_3, 'Strength')
            ylabel(app.UIAxes2_3, 'Density')
            app.UIAxes2_3.XLim = [-3 6];
            app.UIAxes2_3.XTick = [-2 0 2 4 6];
            app.UIAxes2_3.XTickLabel = {'-2'; '0'; '2'; '4'; '6'};
            % Show the figure after all components are created
            app.HT2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HT2UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HT2UIFigure)
        end
    end
end